classdef timeHistoryPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TimeHistoryPlotterUIFigure      matlab.ui.Figure
        DataFilesMenu                   matlab.ui.container.Menu
        LoadMenu_2                      matlab.ui.container.Menu
        LoadDemoDataMenu                matlab.ui.container.Menu
        TemplatesMenu                   matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        TimeHistoryDataFilesPanel       matlab.ui.container.Panel
        LoadedFilesListBox              matlab.ui.control.ListBox
        DeleteButton                    matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        DataChannelswithinselectedfilePanel  matlab.ui.container.Panel
        SearchDataChannelsEditField     matlab.ui.control.EditField
        SearchEditFieldLabel            matlab.ui.control.Label
        DataChannelsListBox             matlab.ui.control.ListBox
        LinePropertiesPanel             matlab.ui.container.Panel
        UnitsDropDown                   matlab.ui.control.DropDown
        UnitsDropDownLabel              matlab.ui.control.Label
        BiastoZeroLineCheckBox          matlab.ui.control.CheckBox
        DifferentiateLineCheckBox       matlab.ui.control.CheckBox
        IntegrateLineCheckBox           matlab.ui.control.CheckBox
        yyaxisRightLineCheckBox         matlab.ui.control.CheckBox
        DontShowLineCheckBox            matlab.ui.control.CheckBox
        MinCheckBox                     matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        LineStyleTab                    matlab.ui.container.Tab
        StyleDropDown                   matlab.ui.control.DropDown
        StyleDropDownLabel              matlab.ui.control.Label
        MarkerDropDown                  matlab.ui.control.DropDown
        MarkerDropDownLabel             matlab.ui.control.Label
        LineWidthDropDown               matlab.ui.control.DropDown
        LineWidthDropDownLabel          matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        FiltersTab                      matlab.ui.container.Tab
        ApplyFilterLineCheckBox         matlab.ui.control.CheckBox
        ApplySmoothDataLineCheckBox     matlab.ui.control.CheckBox
        FilterbLineEditField            matlab.ui.control.EditField
        FilterbEditFieldLabel           matlab.ui.control.Label
        FilteraLineEditField            matlab.ui.control.EditField
        FilteraEditFieldLabel           matlab.ui.control.Label
        WindowLineEditField             matlab.ui.control.EditField
        SmoothDataWindowLabel           matlab.ui.control.Label
        MethodLineDropDown              matlab.ui.control.DropDown
        SmoothDataMethodLabel           matlab.ui.control.Label
        ToAddTab                        matlab.ui.container.Tab
        ratelimiterLabel                matlab.ui.control.Label
        errorbarLabel                   matlab.ui.control.Label
        MaxCheckBox                     matlab.ui.control.CheckBox
        DataChannelsinCurrentAxisPanel  matlab.ui.container.Panel
        CurrentAxisListBox              matlab.ui.control.ListBox
        DeleteLineButton                matlab.ui.control.Button
        AddChanneltoAxisButton          matlab.ui.control.Button
        AxisPropertiesPanel             matlab.ui.container.Panel
        HorizontalLinesEditField        matlab.ui.control.EditField
        HorizontalLinesEditFieldLabel   matlab.ui.control.Label
        LegendTitleEditField            matlab.ui.control.EditField
        LegendTitleEditFieldLabel       matlab.ui.control.Label
        logyCheckBox                    matlab.ui.control.CheckBox
        ymaxEditField                   matlab.ui.control.NumericEditField
        ymaxEditFieldLabel              matlab.ui.control.Label
        yminEditField                   matlab.ui.control.NumericEditField
        yminEditFieldLabel              matlab.ui.control.Label
        DontShowAxisCheckBox            matlab.ui.control.CheckBox
        LegendLocationDropDown          matlab.ui.control.DropDown
        LegendLocationDropDownLabel     matlab.ui.control.Label
        TightCheckBox                   matlab.ui.control.CheckBox
        AxisListPanel                   matlab.ui.container.Panel
        AxisListListBox                 matlab.ui.control.ListBox
        RenameAxisButton                matlab.ui.control.Button
        DeleteAxisButton                matlab.ui.control.Button
        CopyAxisButton                  matlab.ui.control.Button
        NewButton                       matlab.ui.control.Button
        FigurePropertiesPanel           matlab.ui.container.Panel
        DateTimeFormatEditField         matlab.ui.control.EditField
        DateTimeFormatEditFieldLabel    matlab.ui.control.Label
        TextInterpreterDropDown         matlab.ui.control.DropDown
        TextInterpreterDropDownLabel    matlab.ui.control.Label
        TemplateNameinsgtitleCheckBox   matlab.ui.control.CheckBox
        sgtitleEditField                matlab.ui.control.EditField
        sgtitleEditFieldLabel           matlab.ui.control.Label
        PostxlabeltextEditField         matlab.ui.control.EditField
        PostxlabeltextEditFieldLabel    matlab.ui.control.Label
        VerticalLinesEditField          matlab.ui.control.EditField
        VerticalLinesEditFieldLabel     matlab.ui.control.Label
        TimeMaxEditField                matlab.ui.control.NumericEditField
        TimeMaxEditFieldLabel           matlab.ui.control.Label
        TimeMinEditField                matlab.ui.control.NumericEditField
        TimeMinEditFieldLabel           matlab.ui.control.Label
        logxCheckBox                    matlab.ui.control.CheckBox
        TileSpaceDropDown               matlab.ui.control.DropDown
        TileSpaceDropDownLabel          matlab.ui.control.Label
        BoldCheckBox                    matlab.ui.control.CheckBox
        FigureNumDropDown               matlab.ui.control.DropDown
        FigureDropDownLabel             matlab.ui.control.Label
        LinkAxesCheckBox                matlab.ui.control.CheckBox
        FileNameinsgtitleCheckBox       matlab.ui.control.CheckBox
        FigureSizeDropDown              matlab.ui.control.DropDown
        FigureSizeLabel                 matlab.ui.control.Label
        MinorGridCheckBox               matlab.ui.control.CheckBox
        IndependentChannelDropDown      matlab.ui.control.DropDown
        IndependentChannelDropDownLabel  matlab.ui.control.Label
        MajorGridCheckBox               matlab.ui.control.CheckBox
        PrintButton                     matlab.ui.control.Button
        keyboardButton                  matlab.ui.control.Button
        PlottingTemplatesPanel          matlab.ui.container.Panel
        PlottingTemplatesListBox        matlab.ui.control.ListBox
        RenameTemplateButton            matlab.ui.control.Button
        DeleteTemplateButton            matlab.ui.control.Button
        CopyTemplateButton              matlab.ui.control.Button
        NewTemplateButton               matlab.ui.control.Button
        clcButton                       matlab.ui.control.Button
        CloseallButton                  matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        tHData      % All time history data
        templates   % All templates
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                
            % Setup gui for use
            guiControl.onStartUp(app);


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            dataLoader.topLevel(app);
        end

        % Value changed function: LoadedFilesListBox
        function LoadedFilesListBoxValueChanged(app, event)
            guiControl.listBoxSelectedChanged.loadedFiles(app);
        end

        % Button pushed function: clcButton
        function clcButtonPushed(app, event)
            clc
        end

        % Button pushed function: NewTemplateButton
        function NewTemplateButtonPushed(app, event)
            guiControl.makeNewTemplate(app, false);
        end

        % Drop down opening function: IndependentChannelDropDown
        function IndependentChannelDropDownOpening(app, event)
            guiControl.independentChannel_DropDownOpen(app);
        end

        % Button pushed function: AddChanneltoAxisButton
        function AddChanneltoAxisButtonPushed(app, event)
            guiControl.addCurChannelToCurrentAxis(app);
        end

        % Value changed function: CurrentAxisListBox
        function CurrentAxisListBoxValueChanged(app, event)
            guiControl.listBoxSelectedChanged.dataChannelsInCurrentAxis(app, ...
                event.PreviousValueIndex);
        end

        % Value changed function: PlottingTemplatesListBox
        function PlottingTemplatesListBoxValueChanged(app, event)
            guiControl.listBoxSelectedChanged.templates(app, event.PreviousValueIndex);
        end

        % Button pushed function: keyboardButton
        function keyboardButtonPushed(app, event)
            keyboard
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            guiControl.makeNewAxis(app);
        end

        % Value changed function: AxisListListBox
        function AxisListListBoxValueChanged(app, event)
            guiControl.listBoxSelectedChanged.axis(app, [], event.PreviousValueIndex, []);
            
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
            guiControl.print(app.templates);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plotting.topLevel(app);
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all
        end

        % Button pushed function: RenameTemplateButton
        function RenameTemplateButtonPushed(app, event)
            guiControl.renameTemplate(app, []);
        end

        % Button pushed function: CopyTemplateButton
        function CopyTemplateButtonPushed(app, event)
            guiControl.copyTemplate(app);
        end

        % Button pushed function: DeleteTemplateButton
        function DeleteTemplateButtonPushed(app, event)
            guiControl.deleteTemplate(app);
        end

        % Button pushed function: CopyAxisButton
        function CopyAxisButtonPushed(app, event)
            guiControl.copyAxis(app);
        end

        % Button pushed function: DeleteAxisButton
        function DeleteAxisButtonPushed(app, event)
            guiControl.deleteAxis(app);
        end

        % Button pushed function: RenameAxisButton
        function RenameAxisButtonPushed(app, event)
            guiControl.renameAxis(app);
        end

        % Button pushed function: DeleteLineButton
        function DeleteLineButtonPushed(app, event)
            guiControl.deleteLine(app);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            guiControl.deleteFile(app);
        end

        % Value changing function: SearchDataChannelsEditField
        function SearchDataChannelsEditFieldValueChanging(app, event)
             guiControl.searchDataChannelNames(app, event.Value);
        end

        % Menu selected function: LoadDemoDataMenu
        function LoadDemoDataMenuSelected(app, event)
            dataLoader.loadDemoData(app);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            guiControl.saveTemplates(app);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            guiControl.loadTemplates(app);
        end

        % Close request function: TimeHistoryPlotterUIFigure
        function TimeHistoryPlotterUIFigureCloseRequest(app, event)
            
            selection = uiconfirm(app.TimeHistoryPlotterUIFigure, ...
                "Close ""Time History Plotter""", "Confirm Close", ...
                "Options",["Close","Cancel"], ...
                "Icon","warning");
            switch selection
                case {'Close'}
                    delete(app)
                case {'Cancel'}
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TimeHistoryPlotterUIFigure = uifigure('Visible', 'off');
            app.TimeHistoryPlotterUIFigure.Name = 'Time History Plotter';
            app.TimeHistoryPlotterUIFigure.CloseRequestFcn = createCallbackFcn(app, @TimeHistoryPlotterUIFigureCloseRequest, true);
            app.DataFilesMenu = uimenu(app.TimeHistoryPlotterUIFigure);
            app.DataFilesMenu.Text = 'Data Files';
            app.LoadMenu_2 = uimenu(app.DataFilesMenu);
            app.LoadMenu_2.Text = 'Load';
            app.LoadDemoDataMenu = uimenu(app.DataFilesMenu);
            app.LoadDemoDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDemoDataMenuSelected, true);
            app.LoadDemoDataMenu.Text = 'Load Demo Data';
            app.TemplatesMenu = uimenu(app.TimeHistoryPlotterUIFigure);
            app.TemplatesMenu.Text = 'Templates';
            app.LoadMenu = uimenu(app.TemplatesMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.TemplatesMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.PlotButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CloseallButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.clcButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.clcButton.ButtonPushedFcn = createCallbackFcn(app, @clcButtonPushed, true);
            app.clcButton.Text = 'clc';
            app.PlottingTemplatesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.PlottingTemplatesPanel.Title = 'Plotting Templates';
            app.NewTemplateButton = uibutton(app.PlottingTemplatesPanel, 'push');
            app.NewTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @NewTemplateButtonPushed, true);
            app.NewTemplateButton.Text = 'New';
            app.CopyTemplateButton = uibutton(app.PlottingTemplatesPanel, 'push');
            app.CopyTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @CopyTemplateButtonPushed, true);
            app.CopyTemplateButton.Text = 'Copy';
            app.DeleteTemplateButton = uibutton(app.PlottingTemplatesPanel, 'push');
            app.DeleteTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTemplateButtonPushed, true);
            app.DeleteTemplateButton.Text = 'Delete';
            app.RenameTemplateButton = uibutton(app.PlottingTemplatesPanel, 'push');
            app.RenameTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @RenameTemplateButtonPushed, true);
            app.RenameTemplateButton.Text = 'Rename';
            app.PlottingTemplatesListBox = uilistbox(app.PlottingTemplatesPanel);
            app.PlottingTemplatesListBox.ValueChangedFcn = createCallbackFcn(app, @PlottingTemplatesListBoxValueChanged, true);
            app.keyboardButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.keyboardButton.ButtonPushedFcn = createCallbackFcn(app, @keyboardButtonPushed, true);
            app.keyboardButton.Text = 'keyboard';
            app.PrintButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Text = 'Print';
            app.FigurePropertiesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.FigurePropertiesPanel.Title = 'Figure Properties';
            app.MajorGridCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.MajorGridCheckBox.Text = 'Major Grid';
            app.IndependentChannelDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.IndependentChannelDropDownLabel.Text = 'Independent Channel';
            app.IndependentChannelDropDown = uidropdown(app.FigurePropertiesPanel);
            app.IndependentChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @IndependentChannelDropDownOpening, true);
            app.MinorGridCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.MinorGridCheckBox.Text = 'Minor Grid';
            app.FigureSizeLabel = uilabel(app.FigurePropertiesPanel);
            app.FigureSizeLabel.Text = 'Figure Size';
            app.FigureSizeDropDown = uidropdown(app.FigurePropertiesPanel);
            app.FigureSizeDropDown.Items = {'Dont change', '560, 600 - small portrait', '740, 790 - medium portrait', '890, 900 - portrait', '1100, 650 - small landscape', '1240, 800 - medium landscape', '1585, 960 - landscape'};
            app.FigureSizeDropDown.Value = 'Dont change';
            app.FileNameinsgtitleCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.FileNameinsgtitleCheckBox.Text = 'File Name in sgtitle';
            app.LinkAxesCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.LinkAxesCheckBox.Text = 'Link Axes';
            app.FigureDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.FigureDropDownLabel.Text = 'Figure';
            app.FigureNumDropDown = uidropdown(app.FigurePropertiesPanel);
            app.FigureNumDropDown.Items = {'gcf', 'figure', 'figure(1)', 'figure(2)', 'figure(3)', 'figure(4)', 'figure(5)', 'figure(6)', 'figure(7)', 'figure(8)', 'figure(9)'};
            app.FigureNumDropDown.Value = 'gcf';
            app.BoldCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.BoldCheckBox.Text = 'Bold';
            app.TileSpaceDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.TileSpaceDropDownLabel.Text = 'Tile Space';
            app.TileSpaceDropDown = uidropdown(app.FigurePropertiesPanel);
            app.TileSpaceDropDown.Items = {'loose', 'compact', 'tight', 'none'};
            app.TileSpaceDropDown.Value = 'loose';
            app.logxCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.logxCheckBox.Text = 'logx';
            app.TimeMinEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.TimeMinEditFieldLabel.Text = 'Time Min';
            app.TimeMinEditField = uieditfield(app.FigurePropertiesPanel, 'numeric');
            app.TimeMinEditField.Value = -Inf;
            app.TimeMaxEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.TimeMaxEditFieldLabel.Text = 'Time Max';
            app.TimeMaxEditField = uieditfield(app.FigurePropertiesPanel, 'numeric');
            app.TimeMaxEditField.Value = Inf;
            app.VerticalLinesEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.VerticalLinesEditFieldLabel.Text = 'Vertical Lines';
            app.VerticalLinesEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.PostxlabeltextEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.PostxlabeltextEditFieldLabel.Text = 'Post xlabel text';
            app.PostxlabeltextEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.sgtitleEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.sgtitleEditFieldLabel.Text = 'sgtitle';
            app.sgtitleEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.TemplateNameinsgtitleCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.TemplateNameinsgtitleCheckBox.Enable = 'off';
            app.TemplateNameinsgtitleCheckBox.Text = 'Template Name in sgtitle';
            app.TextInterpreterDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.TextInterpreterDropDownLabel.Enable = 'off';
            app.TextInterpreterDropDownLabel.Text = 'Text Interpreter';
            app.TextInterpreterDropDown = uidropdown(app.FigurePropertiesPanel);
            app.TextInterpreterDropDown.Items = {'none', 'tex', 'latex'};
            app.TextInterpreterDropDown.Enable = 'off';
            app.TextInterpreterDropDown.Value = 'none';
            app.DateTimeFormatEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.DateTimeFormatEditFieldLabel.Text = 'Date Time Format';
            app.DateTimeFormatEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.AxisListPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.AxisListPanel.Title = 'Axis List';
            app.NewButton = uibutton(app.AxisListPanel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.CopyAxisButton = uibutton(app.AxisListPanel, 'push');
            app.CopyAxisButton.ButtonPushedFcn = createCallbackFcn(app, @CopyAxisButtonPushed, true);
            app.CopyAxisButton.Text = 'Copy';
            app.DeleteAxisButton = uibutton(app.AxisListPanel, 'push');
            app.DeleteAxisButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAxisButtonPushed, true);
            app.DeleteAxisButton.Text = 'Delete';
            app.RenameAxisButton = uibutton(app.AxisListPanel, 'push');
            app.RenameAxisButton.ButtonPushedFcn = createCallbackFcn(app, @RenameAxisButtonPushed, true);
            app.RenameAxisButton.Text = 'Rename';
            app.AxisListListBox = uilistbox(app.AxisListPanel);
            app.AxisListListBox.ValueChangedFcn = createCallbackFcn(app, @AxisListListBoxValueChanged, true);
            app.AxisPropertiesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.AxisPropertiesPanel.Title = 'Axis Properties';
            app.TightCheckBox = uicheckbox(app.AxisPropertiesPanel);
            app.TightCheckBox.Text = 'Tight';
            app.LegendLocationDropDownLabel = uilabel(app.AxisPropertiesPanel);
            app.LegendLocationDropDownLabel.Text = 'Legend Location';
            app.LegendLocationDropDown = uidropdown(app.AxisPropertiesPanel);
            app.LegendLocationDropDown.Items = {'best', 'northeast', 'northwest', 'north', 'south', 'east', 'west', 'southeast', 'southwest', 'northoutside', 'southoutside', 'eastoutside', 'westoutside', 'northeastoutside', 'northwestoutside', 'southeastoutside', 'southwestoutside', 'bestoutside', 'none'};
            app.LegendLocationDropDown.Value = 'best';
            app.DontShowAxisCheckBox = uicheckbox(app.AxisPropertiesPanel);
            app.DontShowAxisCheckBox.Text = 'Dont Show';
            app.yminEditFieldLabel = uilabel(app.AxisPropertiesPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.AxisPropertiesPanel, 'numeric');
            app.ymaxEditFieldLabel = uilabel(app.AxisPropertiesPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.AxisPropertiesPanel, 'numeric');
            app.logyCheckBox = uicheckbox(app.AxisPropertiesPanel);
            app.logyCheckBox.Text = 'logy';
            app.LegendTitleEditFieldLabel = uilabel(app.AxisPropertiesPanel);
            app.LegendTitleEditFieldLabel.Text = 'Legend Title';
            app.LegendTitleEditField = uieditfield(app.AxisPropertiesPanel, 'text');
            app.HorizontalLinesEditFieldLabel = uilabel(app.AxisPropertiesPanel);
            app.HorizontalLinesEditFieldLabel.Text = 'Horizontal Lines';
            app.HorizontalLinesEditField = uieditfield(app.AxisPropertiesPanel, 'text');
            app.DataChannelsinCurrentAxisPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.DataChannelsinCurrentAxisPanel.Title = 'Data Channels in Current Axis';
            app.AddChanneltoAxisButton = uibutton(app.DataChannelsinCurrentAxisPanel, 'push');
            app.AddChanneltoAxisButton.ButtonPushedFcn = createCallbackFcn(app, @AddChanneltoAxisButtonPushed, true);
            app.AddChanneltoAxisButton.Text = 'Add Channel to Axis';
            app.DeleteLineButton = uibutton(app.DataChannelsinCurrentAxisPanel, 'push');
            app.DeleteLineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLineButtonPushed, true);
            app.DeleteLineButton.Text = 'Delete';
            app.CurrentAxisListBox = uilistbox(app.DataChannelsinCurrentAxisPanel);
            app.CurrentAxisListBox.ValueChangedFcn = createCallbackFcn(app, @CurrentAxisListBoxValueChanged, true);
            app.LinePropertiesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.LinePropertiesPanel.Title = 'Line Properties';
            app.MaxCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.MaxCheckBox.Text = 'Max';
            app.TabGroup = uitabgroup(app.LinePropertiesPanel);
            app.LineStyleTab = uitab(app.TabGroup);
            app.LineStyleTab.Title = 'Line Style';
            app.ColorDropDownLabel = uilabel(app.LineStyleTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LineStyleTab);
            app.ColorDropDown.Items = {'automatic', 'black', 'blue', 'orange', 'yellow', 'purple', 'green', 'light blue', 'red', 'magenta'};
            app.ColorDropDown.Value = 'automatic';
            app.LineWidthDropDownLabel = uilabel(app.LineStyleTab);
            app.LineWidthDropDownLabel.Text = 'Line Width';
            app.LineWidthDropDown = uidropdown(app.LineStyleTab);
            app.LineWidthDropDown.Items = {'0.1', '0.25', '0.5', '0.75', '1.0', '1.5', '2.0', '2.5', '3.0', '4.0', '5.0', '6.0'};
            app.LineWidthDropDown.Value = '0.1';
            app.MarkerDropDownLabel = uilabel(app.LineStyleTab);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.LineStyleTab);
            app.MarkerDropDown.Items = {'none', 'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram'};
            app.MarkerDropDown.Value = 'none';
            app.StyleDropDownLabel = uilabel(app.LineStyleTab);
            app.StyleDropDownLabel.Text = 'Style';
            app.StyleDropDown = uidropdown(app.LineStyleTab);
            app.StyleDropDown.Items = {'-', ':', '-.', '--', 'none'};
            app.StyleDropDown.Value = '-';
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.SmoothDataMethodLabel = uilabel(app.FiltersTab);
            app.SmoothDataMethodLabel.Text = 'Method';
            app.MethodLineDropDown = uidropdown(app.FiltersTab);
            app.MethodLineDropDown.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.MethodLineDropDown.Value = 'movmean';
            app.SmoothDataWindowLabel = uilabel(app.FiltersTab);
            app.SmoothDataWindowLabel.Text = 'Window';
            app.WindowLineEditField = uieditfield(app.FiltersTab, 'text');
            app.WindowLineEditField.Value = '4';
            app.FilteraEditFieldLabel = uilabel(app.FiltersTab);
            app.FilteraEditFieldLabel.Text = '"Filter" a';
            app.FilteraLineEditField = uieditfield(app.FiltersTab, 'text');
            app.FilteraLineEditField.Value = '1';
            app.FilterbEditFieldLabel = uilabel(app.FiltersTab);
            app.FilterbEditFieldLabel.Text = '"Filter" b';
            app.FilterbLineEditField = uieditfield(app.FiltersTab, 'text');
            app.FilterbLineEditField.Value = '0.2 0.2 0.2 0.2 0.2';
            app.ApplySmoothDataLineCheckBox = uicheckbox(app.FiltersTab);
            app.ApplySmoothDataLineCheckBox.Text = 'Apply "SmoothData"';
            app.ApplyFilterLineCheckBox = uicheckbox(app.FiltersTab);
            app.ApplyFilterLineCheckBox.Text = 'Apply "Filter"';
            app.ToAddTab = uitab(app.TabGroup);
            app.ToAddTab.Title = 'To Add';
            app.errorbarLabel = uilabel(app.ToAddTab);
            app.errorbarLabel.Text = 'errorbar';
            app.ratelimiterLabel = uilabel(app.ToAddTab);
            app.ratelimiterLabel.Text = 'rate limiter';
            app.MinCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.MinCheckBox.Text = 'Min';
            app.DontShowLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.DontShowLineCheckBox.Text = 'Dont Show';
            app.yyaxisRightLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.yyaxisRightLineCheckBox.Text = 'yyaxis right';
            app.IntegrateLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.IntegrateLineCheckBox.Text = 'Integrate';
            app.DifferentiateLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.DifferentiateLineCheckBox.Text = 'Differentiate';
            app.BiastoZeroLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.BiastoZeroLineCheckBox.Text = 'Bias to Zero';
            app.UnitsDropDownLabel = uilabel(app.LinePropertiesPanel);
            app.UnitsDropDownLabel.Enable = 'off';
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.LinePropertiesPanel);
            app.UnitsDropDown.Editable = 'on';
            app.UnitsDropDown.Enable = 'off';
            app.DataChannelswithinselectedfilePanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.DataChannelswithinselectedfilePanel.Title = 'Data Channels - within selected file';
            app.DataChannelsListBox = uilistbox(app.DataChannelswithinselectedfilePanel);
            app.SearchEditFieldLabel = uilabel(app.DataChannelswithinselectedfilePanel);
            app.SearchEditFieldLabel.Text = 'Search';
            app.SearchDataChannelsEditField = uieditfield(app.DataChannelswithinselectedfilePanel, 'text');
            app.SearchDataChannelsEditField.ValueChangingFcn = createCallbackFcn(app, @SearchDataChannelsEditFieldValueChanging, true);
            app.TimeHistoryDataFilesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.TimeHistoryDataFilesPanel.Title = 'Time History Data Files';
            app.LoadButton = uibutton(app.TimeHistoryDataFilesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DeleteButton = uibutton(app.TimeHistoryDataFilesPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.LoadedFilesListBox = uilistbox(app.TimeHistoryDataFilesPanel);
            app.LoadedFilesListBox.ValueChangedFcn = createCallbackFcn(app, @LoadedFilesListBoxValueChanged, true);
            % Show the figure after all components are created
            app.TimeHistoryPlotterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timeHistoryPlotter

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TimeHistoryPlotterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TimeHistoryPlotterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TimeHistoryPlotterUIFigure)
        end
    end
end